classdef ContrastCorrector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        inputBox                  matlab.ui.control.UIAxes
        inputHisto                matlab.ui.control.UIAxes
        ControlsPanel             matlab.ui.container.Panel
        ImagePathEditFieldLabel   matlab.ui.control.Label
        ImagePath                 matlab.ui.control.EditField
        SelectImageBtn            matlab.ui.control.Button
        TabGroup                  matlab.ui.container.TabGroup
        ProposedHistogramTab      matlab.ui.container.Tab
        vLabel                    matlab.ui.control.Label
        vValBox                   matlab.ui.control.NumericEditField
        rLabel                    matlab.ui.control.Label
        rValBox                   matlab.ui.control.NumericEditField
        AlgorithmParametersLabel  matlab.ui.control.Label
        ParametersforUnsharpMaskingLabel  matlab.ui.control.Label
        SigmaLabel                matlab.ui.control.Label
        sigVal                    matlab.ui.control.NumericEditField
        FilterSizeLabel           matlab.ui.control.Label
        FiltVal                   matlab.ui.control.NumericEditField
        applyBtn                  matlab.ui.control.Button
        PlLabel                   matlab.ui.control.Label
        plVal                     matlab.ui.control.NumericEditField
        KLabel                    matlab.ui.control.Label
        K_val                     matlab.ui.control.NumericEditField
        outputBox                 matlab.ui.control.UIAxes
        outputHisto               matlab.ui.control.UIAxes
        StatusLampLabel           matlab.ui.control.Label
        StatusLamp                matlab.ui.control.Lamp
        EntropyDisplay            matlab.ui.control.Label
        entropy_TB                matlab.ui.control.NumericEditField
        disp_histo                matlab.ui.control.Button
        EntropyDisplay_2          matlab.ui.control.Label
        entropy_TB_2              matlab.ui.control.NumericEditField
        EntropyDisplay_3          matlab.ui.control.Label
        input_entropy             matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.vValBox.Value=0.2;
            app.rValBox.Value=0.5;
            app.plVal.Value=0.0001;
            app.sigVal.Value=1.4;
            app.FiltVal.Value=9;
            app.K_val.Value=0.9;
        end

        % Button pushed function: SelectImageBtn
        function SelectImageBtnButtonPushed(app, event)
        [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           fullname = [filepath, filename];
           app.ImagePath.Value=filepath;
           app.ImagePath.Editable=false;
           ImageFile = imread(fullname);
           [m n channels]=size(ImageFile);
           if channels==3
            ImageFile=rgb2gray(ImageFile);
           end
           imshow(ImageFile,'Parent',app.inputBox);
           input_image_entropy=Backend.discrete_entropy(ImageFile);
           app.input_entropy.Value=input_image_entropy;
           Simple_histogram= histeq(ImageFile);
           imshow(Simple_histogram,'Parent',app.inputHisto);
           image_entropy=Backend.discrete_entropy(Simple_histogram);
           app.entropy_TB_2.Value=image_entropy;
           figure(app.UIFigure)
        end

        % Button pushed function: applyBtn
        function applyBtnButtonPushed(app, event)
            app.StatusLamp.Color='Red';
            pause(2);
            input_image=getimage(app.inputBox);
            v=app.vValBox.Value;
            r=app.rValBox.Value;
            pl=app.plVal.Value;
            sigma=app.sigVal.Value;
            filter=app.FiltVal.Value;
            k=app.K_val.Value;
            if v>1
                v=1;
            elseif v<0
                v=0;
            end
            if r>1
                r=1;
            elseif r<0
                r=0;
            end
            [output_image sharp_output_image]=Backend.modified_histeq(input_image,v,r,sigma,filter,pl,k);
            imshow(uint8(output_image),'Parent',app.outputBox);
            imshow(uint8(sharp_output_image),'Parent',app.outputHisto);
            img_entropy=Backend.discrete_entropy(uint8(sharp_output_image));
            app.entropy_TB.Value=img_entropy;
            app.StatusLamp.Color='Green';
            figure(app.UIFigure)
        end

        % Button pushed function: disp_histo
        function disp_histoButtonPushed(app, event)
            Input_HE=getimage(app.inputHisto);
            Output_HE=getimage(app.outputHisto);
            Input_img=getimage(app.inputBox);
            figure
            subplot(1,3,1)
            imhist(Input_img)
            title('Input Image')
            subplot(1,3,2)
            imhist(Input_HE,255);
            title('Input Image HEed')
            subplot(1,3,3)
            imhist(Output_HE,255);
            title('Output Image')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.inputBox = uiaxes(app.UIFigure);
            title(app.inputBox, {'Input Image'; ''})
            xlabel(app.inputBox, 'X')
            ylabel(app.inputBox, 'Y')
            app.inputHisto = uiaxes(app.UIFigure);
            title(app.inputHisto, {'Simple Histogram Equalization'; ''; ''})
            xlabel(app.inputHisto, 'X')
            ylabel(app.inputHisto, 'Y')
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.ImagePathEditFieldLabel = uilabel(app.ControlsPanel);
            app.ImagePathEditFieldLabel.Text = {'Image Path:'; ''};
            app.ImagePath = uieditfield(app.ControlsPanel, 'text');
            app.SelectImageBtn = uibutton(app.ControlsPanel, 'push');
            app.SelectImageBtn.ButtonPushedFcn = createCallbackFcn(app, @SelectImageBtnButtonPushed, true);
            app.SelectImageBtn.Text = {'Select Image'; ''};
            app.TabGroup = uitabgroup(app.ControlsPanel);
            app.ProposedHistogramTab = uitab(app.TabGroup);
            app.ProposedHistogramTab.Title = 'Proposed Histogram';
            app.vLabel = uilabel(app.ProposedHistogramTab);
            app.vLabel.Text = {'v :'; ''};
            app.vValBox = uieditfield(app.ProposedHistogramTab, 'numeric');
            app.rLabel = uilabel(app.ProposedHistogramTab);
            app.rLabel.Text = {'r : '; ''};
            app.rValBox = uieditfield(app.ProposedHistogramTab, 'numeric');
            app.AlgorithmParametersLabel = uilabel(app.ProposedHistogramTab);
            app.AlgorithmParametersLabel.Text = {'Algorithm Parameters'; ''};
            app.ParametersforUnsharpMaskingLabel = uilabel(app.ProposedHistogramTab);
            app.ParametersforUnsharpMaskingLabel.Text = {'Parameters for Unsharp Masking'; ''};
            app.SigmaLabel = uilabel(app.ProposedHistogramTab);
            app.SigmaLabel.Text = {'Sigma :'; ''};
            app.sigVal = uieditfield(app.ProposedHistogramTab, 'numeric');
            app.FilterSizeLabel = uilabel(app.ProposedHistogramTab);
            app.FilterSizeLabel.Text = {'Filter Size:'; ''};
            app.FiltVal = uieditfield(app.ProposedHistogramTab, 'numeric');
            app.applyBtn = uibutton(app.ProposedHistogramTab, 'push');
            app.applyBtn.ButtonPushedFcn = createCallbackFcn(app, @applyBtnButtonPushed, true);
            app.applyBtn.Text = {'Apply'; ''};
            app.PlLabel = uilabel(app.ProposedHistogramTab);
            app.PlLabel.Text = 'Pl :';
            app.plVal = uieditfield(app.ProposedHistogramTab, 'numeric');
            app.KLabel = uilabel(app.ProposedHistogramTab);
            app.KLabel.Text = 'K:';
            app.K_val = uieditfield(app.ProposedHistogramTab, 'numeric');
            app.outputBox = uiaxes(app.UIFigure);
            title(app.outputBox, 'Proposed Method Without Sharpening')
            xlabel(app.outputBox, 'X')
            ylabel(app.outputBox, 'Y')
            app.outputHisto = uiaxes(app.UIFigure);
            title(app.outputHisto, {'Proposed Method With Sharpening'; ''})
            xlabel(app.outputHisto, 'X')
            ylabel(app.outputHisto, 'Y')
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = {'Status'; ''};
            app.StatusLamp = uilamp(app.UIFigure);
            app.EntropyDisplay = uilabel(app.UIFigure);
            app.EntropyDisplay.Text = {'Output Image Entropy:'; ''};
            app.entropy_TB = uieditfield(app.UIFigure, 'numeric');
            app.entropy_TB.Editable = 'off';
            app.disp_histo = uibutton(app.UIFigure, 'push');
            app.disp_histo.ButtonPushedFcn = createCallbackFcn(app, @disp_histoButtonPushed, true);
            app.disp_histo.Text = 'Display Histograms';
            app.EntropyDisplay_2 = uilabel(app.UIFigure);
            app.EntropyDisplay_2.Text = {'Input Image HE'; ' Entropy:'; ''};
            app.entropy_TB_2 = uieditfield(app.UIFigure, 'numeric');
            app.entropy_TB_2.Editable = 'off';
            app.EntropyDisplay_3 = uilabel(app.UIFigure);
            app.EntropyDisplay_3.Text = {'Input Image Entropy:'; ''};
            app.input_entropy = uieditfield(app.UIFigure, 'numeric');
            app.input_entropy.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ContrastCorrector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end